classdef firstPrototypeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        UIAxes_2  matlab.ui.control.UIAxes
        UIAxes_3  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property12 % Description
    end

    properties (Access = private)
        Property11 % Description
    end

    properties (Access = private)
        Property10 % Description
    end

    properties (Access = private)
        Property9 % Description
    end

    properties (Access = private)
        Property8 % Description
    end

    properties (Access = private)
        Property7 % Description
    end

    properties (Access = private)
        Property6 % Description
    end

    properties (Access = public)
        Property5 % Description
    end

    properties (Access = public)
        Property4 % Description
    end

    properties (Access = private)
        Property3 % Description
    end

    properties (Access = private)
        Property2 % Description
    end

    properties (Access = public)
        Property % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Cleaning
            clc;
            close all hidden;
            
            %% stereoParams
            load('stereoParams10.mat'); %loads it back in and Matlab recognises it is a structure
            stereoParams1 = stereoParameters(stereoParams); % recreates the stereo parameters object 
            base = 72.895433590710810   ;    
            pixelSize = 0.003482560000000;
            f = 9.214831822060825;
            
            %% Camera setup
            imaqreset
            % Right camera
            global right;
            right = videoinput('winvideo', 2, 'RGB24_1280x1024');
            % Left camera
            global left;
            left = videoinput('winvideo', 1, 'RGB24_1280x1024');%left
            
            set([left right],'FramesPerTrigger',Inf);
            set([left right], 'ReturnedColorspace', 'RGB'); 
            src_left = getselectedsource(left);
            src_left.VerticalFlip = 'on';
            
            src_right = getselectedsource(right);
            src_right.VerticalFlip = 'on';
            
            global videoPlayer;
            videoPlayer = vision.VideoPlayer;
            global foregroundPlayer;
            foregroundPlayer = vision.VideoPlayer;
           
                        
            %% Create blob analysis objects
            blobAna = vision.BlobAnalysis('BoundingBoxOutputPort', true,...
                'AreaOutputPort', false, 'CentroidOutputPort', false,...
                'MinimumBlobArea', 500);
            
            
            %% Loop through video
            start([left right]);
            while islogging([left right]) 
                %% Acquire 1 frame
                I1 = 3.*getsnapshot(left);
                I2 = 3.*getsnapshot(right);
            
                % Rectify the image pairs
                [J1s, J2s] = rectifyStereoImages(I1(:,:,2), I2(:,:,2), stereoParams1, 'OutputView','valid');
            
                %% Disparity
                disparityRange = [16 112];
                blockSize = 5; %% for point pattern
            
                %% Histogrammausgleich
                J1s = histeq(J1s);
                J2s = histeq(J2s);
                %% Median filter
                J1s = medfilt2(J1s, [5 5]);
                J2s = medfilt2(J2s, [5 5]);
            
                %% Disparity berechnen
                disparityMap = disparity(J1s, J2s,  'BlockSize', 5,  'ContrastThreshold', 0.0001, 'UniquenessThreshold', 0,...
                        'DistanceThreshold', [],  ...
                        'DisparityRange', disparityRange );
            
                depth = abs(base) *f ./ (disparityMap*pixelSize) ;
                depth = depth ./ 1000;
                %% Median
                 depth = medfilt2(depth, [5 5]);
            
                %% video processing
                depth_mask = depth;
                %% Filter objects from 1.8m to 2.2 m
                depth_mask( (depth_mask < 1.8) | (depth_mask > 2.2)) = 0;
                depth_mask( (depth_mask >= 1.8) & (depth_mask <= 2.2) ) = 1;
                cleanFG = depth_mask;
                cleanFG = imopen(cleanFG, strel('Disk',15));
                cleanFG = imclose(cleanFG, strel('Disk',15));
                cleanFG = logical(cleanFG);
                
                % detect the connected components with the specified minumum area and
                % compute their bouding boxes
                bbox = step(blobAna, cleanFG);
                % Draw bounding boxes around
                result = insertShape(J1s, 'Rectangle', bbox, 'Color', 'green', 'LineWidth', 3);
                
                %% Display output
                %step(videoPlayer, result);
                imshow(result, 'Parent', app.UIAxes);
                
                %step(foregroundPlayer, cleanFG);
                imshow(foregroundPlayer, 'Parent', app.UIAxes_2);
                
                depth_show = mat2gray(depth);
                %step(disPlayer, depth_show);
                imshow(depth_show, 'Parent', app.UIAxes_3);
                
                flushdata(right);
                flushdata(left);
               
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            
            global right;
            global left;
            delete(left);
            delete(right);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Left camera')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Binary mask')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Depth map')
        end
    end

    methods (Access = public)

        % Construct app
        function app = firstPrototypeGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end